classdef NeuroChannelSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout4              matlab.ui.container.GridLayout
        CancelButton             matlab.ui.control.Button
        OkButton                 matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        LayoutPanel              matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        PredefinedDropDown       matlab.ui.control.DropDown
        PredefinedDropDownLabel  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        InputLabels % Description
        LayoutName
        NumInputLabels
        SelectedLabels
        SelectedChannels
    end

    
    methods (Access = private)
        
        function init(app, InputLabels, LayoutName)
            
            % Get InputLabels and LayoutName
            app.InputLabels      = InputLabels';
            app.LayoutName       = LayoutName;
            app.NumInputLabels   = length(app.InputLabels);
       
            app.SelectedLabels   = cell(app.NumInputLabels, 1);
            app.SelectedChannels = false(app.NumInputLabels, 1);
            

            % Set table properties
            app.UITable.ColumnEditable(3:4) = true;
            addStyle(app.UITable, uistyle('HorizontalAlignment','center'));
        end
        
        function populate(app)

            index    = (1:app.NumInputLabels)';
            ilabels  = app.InputLabels;
            slabels  = app.SelectedLabels;
            selected = true(app.NumInputLabels, 1);

            excluded = find(contains(ilabels, {'Status', 'EOG', 'Trigger'}));
            if(isempty(excluded) == false)
                selected(excluded) = false;
            end
            

            app.UITable.Data = table(index, ilabels, slabels, selected);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, InputLabels, LayoutName)

            app.init(InputLabels, LayoutName);
            app.populate(); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.MainApp.LayoutDropDown.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'#'; 'Data Labels'; 'Custom Labels'; 'Included'};
            app.UITable.RowName = {};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LayoutPanel = uipanel(app.GridLayout2);
            app.LayoutPanel.Title = 'Layout:';
            app.GridLayout3 = uigridlayout(app.LayoutPanel);
            app.PredefinedDropDownLabel = uilabel(app.GridLayout3);
            app.PredefinedDropDownLabel.Text = 'Predefined:';
            app.PredefinedDropDown = uidropdown(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ResetButton = uibutton(app.GridLayout4, 'push');
            app.ResetButton.Text = 'Reset';
            app.OkButton = uibutton(app.GridLayout4, 'push');
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.GridLayout4, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroChannelSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end