classdef FileSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        GridLayout8            matlab.ui.container.GridLayout
        MontageSelector        MontageSelector
        DataInfoOptions        DataInfoOptions
        GridLayout2            matlab.ui.container.GridLayout
        SelectedfilesPanel     matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        SelectedBox            matlab.ui.control.ListBox
        CurrentdirectoryPanel  matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        BrowserBox             matlab.ui.control.ListBox
    end

    properties (Access = private)
        options
    end
    
    
    methods (Access = private)
       
        %% Initialize the GUI
        function init(comp) 
            comp.DataInfoOptions.reset();
            comp.MontageSelector.reset();
        end

        function add_selected_file(comp, filepath)
            [~, filename, ext] = fileparts(filepath);

            if any(strcmpi(comp.SelectedBox.Items, [filename ext])) == false
                comp.SelectedBox.Items{end+1}     = [filename ext];
                comp.SelectedBox.ItemsData{end+1} = comp.get_file_info(filepath);
            end
        end

        function remove_selected_file(comp, fileid)
            index  = 1:length(comp.SelectedBox.Items);
            rmindx = setdiff(index, fileid);
            comp.SelectedBox.Items = comp.SelectedBox.Items(rmindx);
            comp.SelectedBox.ItemsData = comp.SelectedBox.ItemsData(rmindx);
        end

        function update_subject_id(comp) 
            if isempty(comp.SelectedBox.ItemsData)
                comp.DataInfoOptions.set_subject_id('');
                return;
            end

            subject_list = {cell2mat(comp.SelectedBox.ItemsData).subject};
          
            if(length(subject_list) > 1 && isequal(subject_list{:}) == false)
                comp.DataInfoOptions.set_invalid_subject_id();
            else
                comp.DataInfoOptions.set_subject_id(char(unique(subject_list)));
            end
        end

        function update_samplerate(comp) 
            if isempty(comp.SelectedBox.ItemsData)
                comp.DataInfoOptions.set_samplerate([]);
                return;
            end

            samplerate_list = cell2mat(comp.SelectedBox.ItemsData).samplerate;
          
            if(length(unique(samplerate_list)) > 1)
                comp.DataInfoOptions.set_invalid_samplerate();
            else
                comp.DataInfoOptions.set_samplerate(unique(samplerate_list));
            end
        end

        function update_input_channels(comp) 
            if isempty(comp.SelectedBox.ItemsData)
                comp.DataInfoOptions.set_input_channels({});
                return;
            end

            input_channels_list = {cell2mat(comp.SelectedBox.ItemsData).labels};
          
            if( length(input_channels_list) > 1 && isequal(input_channels_list{:}) == false)
                comp.DataInfoOptions.set_invalid_input_channels();
            else
                comp.DataInfoOptions.set_input_channels(input_channels_list{1});
            end
        end
           
        
        function get_current_directory(comp, path)
            entries = dir(path);
            names = {entries.name}; 
            index  = 1:length(entries);
            rmindx = find(contains('.', names));
            entries = entries(setdiff(index, rmindx));
            comp.BrowserBox.Items = {entries.name};
            comp.BrowserBox.ItemsData = entries;
            comp.BrowserBox.Value = {};
        end    


        function update_montage_channels(comp)
            montage = comp.MontageSelector.retrieve();
            input   = comp.DataInfoOptions.retrieve();

            if( isempty(montage.labels) == true || isempty(input.labels) == true)
                comp.MontageSelector.disable_montage_show();
                return;
            end

            if(length(montage.labels) > length(input.labels))
                comp.MontageSelector.set_invalid_montage_channels();
                comp.MontageSelector.disable_montage_show();
            else
                comp.MontageSelector.enable_montage_show();
            end

        end
        
        %% Get file info
        function info = get_file_info(~, filepath)
            h = neuroio_import_header(filepath);
            [~, filename] = fileparts(filepath);
            info.filepath   = filepath;
            info.samplerate = h.SampleRate;
            info.labels     = h.Label;
            info.labels(contains(lower(info.labels), lower({'trigger', 'status'}))) = [];
            info.subject    = char(regexp(filename, '(\w+).', 'tokens', 'once'));
         
        end
        
    end
    
    methods (Access = public)
        
        %% Reset component
        function reset(comp)
            comp.SelectedBox.Items = {};
            comp.SelectedBox.ItemsData = {};
            comp.DataInfoOptions.reset();
            comp.MontageSelector.reset();
            comp.disable_montage_change();
        end
        
        %% Get selected files
        
        function options = retrieve(comp)
            info    = comp.DataInfoOptions.retrieve();
            montage = comp.MontageSelector.retrieve();

            options.filepaths        = {cell2mat(comp.SelectedBox.ItemsData).filepath}';
            options.subject          = info.subject;
            options.samplerate       = info.samplerate;
            options.channels         = info.labels;
            options.montage          = montage.labels';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
            % Init gui components
            comp.init();

            % Browser box
            comp.get_current_directory(pwd);

        end

        % Clicked callback: BrowserBox
        function BrowserBoxClicked(comp, event)
            selItem = comp.BrowserBox.ItemsData(event.InteractionInformation.Item);
            filepath = fullfile(selItem.folder, selItem.name);
            
            if(selItem.isdir)
                comp.get_current_directory(filepath);
                return;
            end

            [~, ~, ext] = fileparts(filepath);
            
            if( strcmpi(ext, '.gdf') == false)
                return;
            end

            comp.add_selected_file(filepath);

            comp.update_subject_id();
            comp.update_samplerate();
            comp.update_input_channels();
            comp.update_montage_channels();
        end

        % Clicked callback: SelectedBox
        function SelectedBoxClicked(comp, event)
            comp.remove_selected_file(event.InteractionInformation.Item);

            comp.update_subject_id();
            comp.update_samplerate();
            comp.update_input_channels();   
            comp.update_montage_channels();
        end

        % Callback function: MontageSelector
        function MontageSelectorShowClicked(comp, event)
            montage = comp.MontageSelector.retrieve();
            input   = comp.DataInfoOptions.retrieve();
            ChannelsLayout(input.labels, montage.labels');
        end

        % Callback function: MontageSelector
        function MontageSelectorValueChanged(comp, event)
            comp.update_montage_channels();
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1065 369];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.CurrentdirectoryPanel = uipanel(comp.GridLayout2);
            comp.CurrentdirectoryPanel.Title = 'Current directory:';
            comp.GridLayout3 = uigridlayout(comp.CurrentdirectoryPanel);
            comp.BrowserBox = uilistbox(comp.GridLayout3);
            comp.BrowserBox.Items = {};
            comp.BrowserBox.Multiselect = 'on';
            comp.BrowserBox.Tag = 'browserbox';
            comp.BrowserBox.ClickedFcn = matlab.apps.createCallbackFcn(comp, @BrowserBoxClicked, true);
            comp.BrowserBox.Value = {};
            comp.SelectedfilesPanel = uipanel(comp.GridLayout2);
            comp.SelectedfilesPanel.Title = 'Selected file(s):';
            comp.GridLayout4 = uigridlayout(comp.SelectedfilesPanel);
            comp.SelectedBox = uilistbox(comp.GridLayout4);
            comp.SelectedBox.Items = {};
            comp.SelectedBox.ClickedFcn = matlab.apps.createCallbackFcn(comp, @SelectedBoxClicked, true);
            comp.SelectedBox.Value = {};
            comp.GridLayout8 = uigridlayout(comp.GridLayout);
            comp.DataInfoOptions = DataInfoOptions(comp.GridLayout8);
            comp.MontageSelector = MontageSelector(comp.GridLayout8);
            comp.MontageSelector.MontageSelectorShowClickedFcn = matlab.apps.createCallbackFcn(comp, @MontageSelectorShowClicked, true);
            comp.MontageSelector.MontageSelectorValueChangedFcn = matlab.apps.createCallbackFcn(comp, @MontageSelectorValueChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end